classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CutoffFreqLabel            matlab.ui.control.Label
        CutoffEditField            matlab.ui.control.EditField
        PlayFilteredButton         matlab.ui.control.Button
        PlayOriginalButton         matlab.ui.control.Button
        ApplyFilterButton          matlab.ui.control.Button
        LoadAudioButton            matlab.ui.control.Button
        OrderEditField             matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel  matlab.ui.control.Label
        FsEditField                matlab.ui.control.NumericEditField
        SamplingFrequencyLabel     matlab.ui.control.Label
        FilterCategoryDropDown     matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        FilterTypeDropDown         matlab.ui.control.DropDown
        FilterTypeLabel            matlab.ui.control.Label
        SpectrogramAxes            matlab.ui.control.UIAxes
        WaveformAxes               matlab.ui.control.UIAxes
        FreqResponseAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            b; a; % filter coefficients
            Fs; % Sampling frequency
            voice; % Original audio
            filtered_voice; % Filtered audio
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [file, path] = uigetfile('*.wav');
            if isequal(file, 0)
                return;
            end
            [app.voice, fs_voice] = audioread(fullfile(path, file));
            app.voice = app.voice(:,1); % mono
            
            app.Fs = app.FsEditField.Value;
            if fs_voice ~= app.Fs
                app.voice = resample(app.voice, app.Fs, fs_voice);
            end
            uialert(app.UIFigure, 'Audio loaded successfully!', 'Success');
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
                filter_type = app.FilterTypeDropDown.Value;
                filter_kind = app.FilterCategoryDropDown.Value;
                N = app.OrderEditField.Value;
                Wn = str2num(app.CutoffEditField.Value);
                Wn = Wn / (app.Fs / 2);
            
                %% Design Filter
                if filter_type == "FIR"
                    win = hamming(N+1);
                    switch filter_kind
                        case 'Low Pass', app.b = fir1(N, Wn, 'low', win);
                        case 'High Pass', app.b = fir1(N, Wn, 'high', win);
                        case 'Band Pass', app.b = fir1(N, Wn, 'bandpass', win);
                        case 'Band Stop', app.b = fir1(N, Wn, 'stop', win);
                    end
                    app.a = 1;
                else
                    switch filter_kind
                        case 'Low Pass', [app.b, app.a] = butter(N, Wn, 'low');
                        case 'High Pass', [app.b, app.a] = butter(N, Wn, 'high');
                        case 'Band Pass', [app.b, app.a] = butter(N, Wn, 'bandpass');
                        case 'Band Stop', [app.b, app.a] = butter(N, Wn, 'stop');
                    end
                end
            
                %% Filter Signal
                app.filtered_voice = filter(app.b, app.a, app.voice);
                t = (0:length(app.voice)-1)/app.Fs;
            
                %% Frequency Response
                [H, f] = freqz(app.b, app.a, 1024, app.Fs);
                plot(app.FreqResponseAxes, f, abs(H));
                title(app.FreqResponseAxes, 'Magnitude Response');
                xlabel(app.FreqResponseAxes, 'Frequency (Hz)');
                ylabel(app.FreqResponseAxes, 'Magnitude');
            
                %% Time Waveform
                plot(app.WaveformAxes, t, app.voice); hold(app.WaveformAxes, 'on');
                plot(app.WaveformAxes, t, app.filtered_voice); hold(app.WaveformAxes, 'off');
                legend(app.WaveformAxes, 'Original', 'Filtered');
                title(app.WaveformAxes, 'Time Domain Signal');
            
                % Compute spectrogram
                    % Compute spectrogram
                window = 256;
                noverlap = round(window * 0.75);
                nfft = 512;
                
                [~, F, T, P] = spectrogram(app.filtered_voice, window, noverlap, nfft, app.Fs);
                
                % Convert power to dB
                PdB = 10 * log10(P + eps);
                
                % Plot into app.SpectrogramAxes
                cla(app.SpectrogramAxes);
                imagesc(app.SpectrogramAxes, T, F, PdB);
                axis(app.SpectrogramAxes, 'xy');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                title(app.SpectrogramAxes, 'Filtered Spectrogram');
                colorbar(app.SpectrogramAxes);

        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            sound(app.voice, app.Fs);
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            sound(app.filtered_voice, app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FreqResponseAxes = uiaxes(app.UIFigure);
            title(app.FreqResponseAxes, 'Frequency Response')
            xlabel(app.FreqResponseAxes, 'X')
            ylabel(app.FreqResponseAxes, 'Y')
            zlabel(app.FreqResponseAxes, 'Z')
            app.WaveformAxes = uiaxes(app.UIFigure);
            title(app.WaveformAxes, 'Waveform')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.SpectrogramAxes = uiaxes(app.UIFigure);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.FilterTypeLabel = uilabel(app.UIFigure);
            app.FilterTypeLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'FIR', 'IIR'};
            app.FilterTypeDropDown.Value = 'FIR';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Filter Category';
            app.FilterCategoryDropDown = uidropdown(app.UIFigure);
            app.FilterCategoryDropDown.Items = {'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
            app.FilterCategoryDropDown.Value = 'Low Pass';
            app.SamplingFrequencyLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyLabel.Text = {'Sampling '; 'Frequency'};
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.OrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadAudioButton = uibutton(app.UIFigure, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = {'Load '; 'Audio'};
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = {'Design &'; 'Apply Filter'};
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = {'Play Original '; 'Audio'};
            app.PlayFilteredButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = {'Play Filtered '; 'Audio'; ''};
            app.CutoffEditField = uieditfield(app.UIFigure, 'text');
            app.CutoffFreqLabel = uilabel(app.UIFigure);
            app.CutoffFreqLabel.Text = 'Cutoff Freq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end